classdef Tanks < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        VarPanel          matlab.ui.container.Panel
        SVEditFieldLabel  matlab.ui.control.Label
        SVEditField       matlab.ui.control.NumericEditField
        PVEditFieldLabel  matlab.ui.control.Label
        PVEditField       matlab.ui.control.NumericEditField
        MVEditFieldLabel  matlab.ui.control.Label
        MVEditField       matlab.ui.control.NumericEditField
        SwPanel           matlab.ui.container.Panel
        NoiseButton       matlab.ui.control.StateButton
        CtrlButton        matlab.ui.control.StateButton
        RunButton         matlab.ui.control.StateButton
        ParaPanel         matlab.ui.container.Panel
        EditFieldLabel    matlab.ui.control.Label
        DeltaEditField    matlab.ui.control.NumericEditField
        EditField_2Label  matlab.ui.control.Label
        TiEditField       matlab.ui.control.NumericEditField
        EditField_3Label  matlab.ui.control.Label
        TdEditField       matlab.ui.control.NumericEditField
        StsPanel          matlab.ui.container.Panel
        Label             matlab.ui.control.Label
        TimerEditField    matlab.ui.control.NumericEditField
        Label_2           matlab.ui.control.Label
        AccEditField      matlab.ui.control.NumericEditField
        SaveButton        matlab.ui.control.Button
        RightPanel        matlab.ui.container.Panel
        UIAxes            matlab.ui.control.UIAxes
        BarUIAxes         matlab.ui.control.UIAxes
        Label_3           matlab.ui.control.Label
        z3Slider          matlab.ui.control.Slider
        Label_4           matlab.ui.control.Label
        z33Slider         matlab.ui.control.Slider
        ButtonGroup       matlab.ui.container.ButtonGroup
        NoLeakButton      matlab.ui.control.RadioButton
        Leak1Button       matlab.ui.control.RadioButton
        Leak3Button       matlab.ui.control.RadioButton
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        u double;
        y double;
        yk_1 double;
        r double;
        hisCnt double;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.hisCnt=3600;
            app.r=zeros(app.hisCnt,1);
            app.u=zeros(app.hisCnt,1);
            app.y=zeros(app.hisCnt,3);
            app.yk_1=zeros(1,3);
            app.TimerEditField.Value=0;
            app.AccEditField.Value=1;

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {631, 631};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {187, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Value changed function: RunButton
        function RunButtonValueChanged(app, event)
            while (app.RunButton.Value)
                app.TimerEditField.Value=app.TimerEditField.Value+1;
                app.r(1:app.hisCnt-1)=app.r(2:app.hisCnt);
                app.u(1:app.hisCnt-1)=app.u(2:app.hisCnt);
                app.y(1:app.hisCnt-1,:)=app.y(2:app.hisCnt,:);
                app.y(app.hisCnt,:)=app.yk_1;
                
                if (app.CtrlButton.Value)
                    app.r(app.hisCnt)=app.SVEditField.Value;                                       
                    delta=app.DeltaEditField.Value;
                    Ti=app.TiEditField.Value;
                    Td=app.TdEditField.Value;
                    app.u(app.hisCnt)=app.u(app.hisCnt-1)+PID(app.r(app.hisCnt-2:app.hisCnt),app.y(app.hisCnt-2:app.hisCnt,3),delta,Ti,Td);
                    if (app.u(app.hisCnt)>100)
                        app.u(app.hisCnt)=100;
                    elseif (app.u(app.hisCnt)<0)
                        app.u(app.hisCnt)=0;
                    end                    
                    app.MVEditField.Value=app.u(app.hisCnt);
                else
                    app.u(app.hisCnt)=app.MVEditField.Value;                    
                    app.r(app.hisCnt)=app.y(app.hisCnt,3);
                    app.SVEditField.Value=app.r(app.hisCnt);
                end
                param=[0;app.z3Slider.Value;app.z33Slider.Value];
                if (app.Leak1Button.Value)
                    param(1)=1;
                elseif (app.Leak3Button.Value)
                    param(1)=3;
                end
                [t1,y1]=ode45(@(t,y)tank(t,y,app.u(app.hisCnt),param),[0 1],app.y(app.hisCnt,:));
                app.yk_1=y1(end,:);
                for (i=1:3)
                    if (app.yk_1(i)>100)
                        app.yk_1(i)=100;
                    elseif (app.yk_1(i)<0)
                        app.yk_1(i)=0;
                    end                    
                end
                app.PVEditField.Value=app.y(app.hisCnt,3);
                
                plot(app.UIAxes,[app.TimerEditField.Value-app.hisCnt+1:app.TimerEditField.Value]/60,[app.u,app.r,app.y]);
                ylim(app.UIAxes,[0 100]);
                bar(app.BarUIAxes,app.y(app.hisCnt,:));
                pause(1/app.AccEditField.Value);
            end
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            var=[app.u,app.r,app.y];
            save("data.txt",'var',"-ascii");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.VarPanel = uipanel(app.LeftPanel);
            app.VarPanel.Title = '控制器变量';
            app.SVEditFieldLabel = uilabel(app.VarPanel);
            app.SVEditFieldLabel.Text = '设定值SV';
            app.SVEditField = uieditfield(app.VarPanel, 'numeric');
            app.SVEditField.Limits = [0 100];
            app.PVEditFieldLabel = uilabel(app.VarPanel);
            app.PVEditFieldLabel.Text = '过程值PV';
            app.PVEditField = uieditfield(app.VarPanel, 'numeric');
            app.PVEditField.Limits = [0 100];
            app.MVEditFieldLabel = uilabel(app.VarPanel);
            app.MVEditFieldLabel.Text = '操纵值MV';
            app.MVEditField = uieditfield(app.VarPanel, 'numeric');
            app.MVEditField.Limits = [0 100];
            app.SwPanel = uipanel(app.LeftPanel);
            app.SwPanel.Title = '开关';
            app.NoiseButton = uibutton(app.SwPanel, 'state');
            app.NoiseButton.Text = '测量噪声';
            app.CtrlButton = uibutton(app.SwPanel, 'state');
            app.CtrlButton.Text = '自动控制';
            app.RunButton = uibutton(app.SwPanel, 'state');
            app.RunButton.ValueChangedFcn = createCallbackFcn(app, @RunButtonValueChanged, true);
            app.RunButton.Text = '运行';
            app.ParaPanel = uipanel(app.LeftPanel);
            app.ParaPanel.Title = '控制器参数';
            app.EditFieldLabel = uilabel(app.ParaPanel);
            app.EditFieldLabel.Text = '比例带';
            app.DeltaEditField = uieditfield(app.ParaPanel, 'numeric');
            app.DeltaEditField.Limits = [0 Inf];
            app.DeltaEditField.Value = 100;
            app.EditField_2Label = uilabel(app.ParaPanel);
            app.EditField_2Label.Text = '积分时间';
            app.TiEditField = uieditfield(app.ParaPanel, 'numeric');
            app.TiEditField.Limits = [0 Inf];
            app.TiEditField.Value = 9999;
            app.EditField_3Label = uilabel(app.ParaPanel);
            app.EditField_3Label.Text = '微分时间';
            app.TdEditField = uieditfield(app.ParaPanel, 'numeric');
            app.TdEditField.Limits = [0 Inf];
            app.StsPanel = uipanel(app.LeftPanel);
            app.StsPanel.Title = '仿真参数';
            app.Label = uilabel(app.StsPanel);
            app.Label.Text = '运行周期';
            app.TimerEditField = uieditfield(app.StsPanel, 'numeric');
            app.TimerEditField.Limits = [0 Inf];
            app.Label_2 = uilabel(app.StsPanel);
            app.Label_2.Text = '仿真速度';
            app.AccEditField = uieditfield(app.StsPanel, 'numeric');
            app.AccEditField.Limits = [1 30];
            app.AccEditField.Value = 1;
            app.SaveButton = uibutton(app.StsPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = '保存数据';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '时间（分钟）')
            ylabel(app.UIAxes, '变量值（%）')
            app.BarUIAxes = uiaxes(app.RightPanel);
            title(app.BarUIAxes, '')
            xlabel(app.BarUIAxes, '')
            ylabel(app.BarUIAxes, '')
            app.BarUIAxes.YLim = [0 100];
            app.Label_3 = uilabel(app.RightPanel);
            app.Label_3.Text = '放水阀2开度';
            app.z3Slider = uislider(app.RightPanel);
            app.z3Slider.Value = 50;
            app.Label_4 = uilabel(app.RightPanel);
            app.Label_4.Text = '放水阀3开度';
            app.z33Slider = uislider(app.RightPanel);
            app.z33Slider.Value = 10;
            app.ButtonGroup = uibuttongroup(app.RightPanel);
            app.ButtonGroup.Title = '泄水位置';
            app.NoLeakButton = uiradiobutton(app.ButtonGroup);
            app.NoLeakButton.Text = '无';
            app.NoLeakButton.Value = true;
            app.Leak1Button = uiradiobutton(app.ButtonGroup);
            app.Leak1Button.Text = '液柱1';
            app.Leak3Button = uiradiobutton(app.ButtonGroup);
            app.Leak3Button.Text = '液柱3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tanks

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end